classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        RoverUR3JoggingLabel      matlab.ui.control.Label
        XButton                   matlab.ui.control.StateButton
        XButton_2                 matlab.ui.control.StateButton
        YButton                   matlab.ui.control.StateButton
        YButton_2                 matlab.ui.control.StateButton
        ZButton                   matlab.ui.control.StateButton
        ZButton_2                 matlab.ui.control.StateButton
        RollButton                matlab.ui.control.StateButton
        RollButton_2              matlab.ui.control.StateButton
        PitchButton               matlab.ui.control.StateButton
        PitchButton_2             matlab.ui.control.StateButton
        YawButton                 matlab.ui.control.StateButton
        YawButton_2               matlab.ui.control.StateButton
        ESTOPButton               matlab.ui.control.Button
        ConfirmResumeButton       matlab.ui.control.Button
        q1SliderLabel             matlab.ui.control.Label
        q1Slider                  matlab.ui.control.Slider
        q2SliderLabel             matlab.ui.control.Label
        q2Slider                  matlab.ui.control.Slider
        q3SliderLabel             matlab.ui.control.Label
        q3Slider                  matlab.ui.control.Slider
        q4SliderLabel             matlab.ui.control.Label
        q4Slider                  matlab.ui.control.Slider
        q5SliderLabel             matlab.ui.control.Label
        q5Slider                  matlab.ui.control.Slider
        RoverPositionSliderLabel  matlab.ui.control.Label
        RoverPositionSlider       matlab.ui.control.Slider
        SpawnObstacleButton       matlab.ui.control.Button
        ChooseRobot               matlab.ui.control.StateButton
        q6SliderLabel             matlab.ui.control.Label
        q6Slider                  matlab.ui.control.Slider
        Button                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        
        %Buttons%
        xpos
        xneg
        ypos
        yneg
        zpos
        zneg
        rpos
        rneg
        ppos
        pneg
        ywpos
        ywneg
        %Buttons%
        
        %Velocity%
        vx
        vy
        vz
        wx
        wy
        wz
        
        Kv
        Kw
        %Velocity%
        
        %robot%
        q6
        robot1
        robot2
        currentrobot
        %robot%
        
        %joints%
        qrobot1
        qrobot2
        qlimrobot1
        qlimrobot2
        %joints%
        
        vectorm
        myTimer
    end
    
    methods (Access = private)
        
        % used in updateCalcs to calculate vectorm (Jogging)
        function vectorCalc(app)
            app.vx = app.Kv*(app.xpos - app.xneg);
            app.vy = app.Kv*(app.ypos - app.yneg);
            app.vz = app.Kv*(app.zpos - app.zneg);
            app.wx = app.Kw*(app.rpos - app.rneg);
            app.wy = app.Kw*(app.ppos - app.pneg);
            app.wz = app.Kw*(app.ywpos - app.ywneg);
            app.vectorm = [app.vx app.vy app.vz app.wx app.wy app.wz];
        end
        
        % used in updateApp to calculate q (Jogging)
        function q = qCalc(app)
            lambda = 0.5;
            J = app.robot1.model.jacob0(app.qrobot1); %jacob0 takes q7 and outputs 6x7
            J = J(:,2:7); %dont consider joint 1 (q1)
            
            % DLS J Inverse to calculate joint velocity
            Jinv_dls = inv((J'*J)+lambda^2*eye(6))*J';
            dq = Jinv_dls*app.vectorm';
            
            
            % apply joint velocity to step robot joint angles
            dt = 0.15;
            app.q6 = app.qrobot1(2:7);
            q = app.q6 + dq'*dt;
            app.q6 = q;
            app.qrobot1 = [app.qrobot1(1) app.q6];
        end
        
        function updateApp(app)
            app.vectorCalc
            app.qCalc
        end
        
        function updateQvalues(app)
            if app.currentrobot == 0
                app.RoverPositionSlider.Visible = "on";
                app.RoverPositionSlider.Enable = "on";
                app.RoverPositionSliderLabel.Visible = "on";
                
                app.qrobot1 = app.robot1.model.getpos;
                app.RoverPositionSlider.Limits = app.qlimrobot1(1,:);
                app.q1Slider.Limits = rad2deg(app.qlimrobot1(2,:));
                app.q2Slider.Limits = rad2deg(app.qlimrobot1(3,:));
                app.q3Slider.Limits = rad2deg(app.qlimrobot1(4,:));
                app.q4Slider.Limits = rad2deg(app.qlimrobot1(5,:));
                app.q5Slider.Limits = rad2deg(app.qlimrobot1(6,:));
                app.q6Slider.Limits = rad2deg(app.qlimrobot1(7,:));
                
                app.RoverPositionSlider.Value = app.qrobot1(1);
                app.q1Slider.Value = rad2deg(app.qrobot1(2));
                app.q2Slider.Value = rad2deg(app.qrobot1(3));
                app.q3Slider.Value = rad2deg(app.qrobot1(4));
                app.q4Slider.Value = rad2deg(app.qrobot1(5));
                app.q5Slider.Value = rad2deg(app.qrobot1(6));
                app.q6Slider.Value = rad2deg(app.qrobot1(7));
                
            elseif app.currentrobot == 1
                app.RoverPositionSlider.Visible = "off";
                app.RoverPositionSlider.Enable = "off";
                app.RoverPositionSliderLabel.Visible = "off";
                
                app.qrobot2 = app.robot2.model.getpos;
                app.q1Slider.Limits = rad2deg(app.qlimrobot2(1,:));
                app.q2Slider.Limits = rad2deg(app.qlimrobot2(2,:));
                app.q3Slider.Limits = rad2deg(app.qlimrobot2(3,:));
                app.q4Slider.Limits = rad2deg(app.qlimrobot2(4,:));
                app.q5Slider.Limits = rad2deg(app.qlimrobot2(5,:));
                app.q6Slider.Limits = rad2deg(app.qlimrobot2(6,:));
                
                app.q1Slider.Value = rad2deg(app.qrobot2(1));
                app.q2Slider.Value = rad2deg(app.qrobot2(2));
                app.q3Slider.Value = rad2deg(app.qrobot2(3));
                app.q4Slider.Value = rad2deg(app.qrobot2(4));
                app.q5Slider.Value = rad2deg(app.qrobot2(5));
                app.q6Slider.Value = rad2deg(app.qrobot2(6));
            end
        end
    end
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
    methods (Access = public)
        
        function readButtons(app)
            % function should be in a loop (keeps running)
            controlm(1) = app.xpos;
            controlm(2) = app.xneg;
            controlm(3) = app.ypos;
            controlm(4) = app.yneg;
            controlm(5) = app.zpos;
            controlm(6) = app.zneg;
            controlm(7) = app.rpos;
            controlm(8) = app.rneg;
            controlm(9) = app.ppos;
            controlm(10) = app.pneg;
            controlm(11) = app.ywpos;
            controlm(12) = app.ywneg;
            
            % when there is a change -> update calculations and animate
            if controlm(1)||controlm(2)||controlm(3)||controlm(4)||controlm(5)||controlm(6)
                app.updateApp
            elseif controlm(7)||controlm(8)||controlm(9)||controlm(10)||controlm(11)||controlm(12)
                app.updateApp
            end
                   
            if app.currentrobot == 0
                app.RoverPositionSlider.Value = app.qrobot1(1);
                app.q1Slider.Value = rad2deg(app.qrobot1(2));
                app.q2Slider.Value = rad2deg(app.qrobot1(3));
                app.q3Slider.Value = rad2deg(app.qrobot1(4));
                app.q4Slider.Value = rad2deg(app.qrobot1(5));
                app.q5Slider.Value = rad2deg(app.qrobot1(6));
                app.q6Slider.Value = rad2deg(app.qrobot1(7));
            elseif app.currentrobot == 1
                app.q1Slider.Value = rad2deg(app.qrobot2(1));
                app.q2Slider.Value = rad2deg(app.qrobot2(2));
                app.q3Slider.Value = rad2deg(app.qrobot2(3));
                app.q4Slider.Value = rad2deg(app.qrobot2(4));
                app.q5Slider.Value = rad2deg(app.qrobot2(5));
                app.q6Slider.Value = rad2deg(app.qrobot2(6));
            end
        end
        
        function getRobot(app, robot1, robot2)
            % run function after declaring the object
            app.robot1 = robot1;
            robot1q = app.robot1.model.getpos;
            app.q6 = robot1q(2:7);
            app.qlimrobot1 = app.robot1.model.qlim;
            app.qrobot1 = app.robot1.model.getpos;
            
            app.robot2 = robot2;
            app.qlimrobot2 = app.robot2.model.qlim;
            app.qrobot2 = app.robot2.model.getpos;
            
            app.updateApp;
            app.updateQvalues;
        end
        
        function [qmatrix1,qmatrix2] = animateQvalues(app)
            app.readButtons;
            qmatrix1 = app.qrobot1;
            qmatrix2 = app.qrobot2;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xpos = 0;
            app.xneg = 0;
            app.ypos = 0;
            app.yneg = 0;
            app.zpos = 0;
            app.zneg = 0;
            app.rpos = 0;
            app.rneg = 0;
            app.ppos = 0;
            app.pneg = 0;
            app.ywpos = 0;
            app.ywneg = 0;
            
            app.vx = 0;
            app.vy = 0;
            app.vz = 0;
            app.wx = 0;
            app.wy = 0;
            app.wz = 0;
            
            app.Kv = 0.5;
            app.Kw = 1;
            
            app.currentrobot = 0;
            
            app.myTimer = timer('Period',0.05,...
                'ExecutionMode', 'fixedSpacing', ...
                'TasksToExecute', Inf);
            
            app.myTimer.TimerFcn = @(~,~)app.updateApp;
            
        end

        % Value changed function: XButton
        function XButtonValueChanged(app, event)
            app.xpos = app.XButton.Value;
        end

        % Value changed function: XButton_2
        function XButton_2ValueChanged(app, event)
            app.xneg = app.XButton_2.Value;
        end

        % Value changed function: YButton
        function YButtonValueChanged(app, event)
            app.ypos = app.YButton.Value;
        end

        % Value changed function: YButton_2
        function YButton_2ValueChanged(app, event)
            app.yneg = app.YButton_2.Value;
        end

        % Value changed function: ZButton
        function ZButtonValueChanged(app, event)
            app.zpos = app.ZButton.Value;
        end

        % Value changed function: ZButton_2
        function ZButton_2ValueChanged(app, event)
            app.zneg = app.ZButton_2.Value;
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            %%%%%%%%%%Include ESTOP stop function%%%%%%%%%%
            app.ConfirmResumeButton.Visible = 'on';
            app.ESTOPButton.Enable = 'off';
            app.ConfirmResumeButton.Enable = 'on';
        end

        % Button pushed function: ConfirmResumeButton
        function ConfirmResumeButtonPushed(app, event)
            %%%%%%%%%%Include ESTOP resume function%%%%%%%%%%
            app.ConfirmResumeButton.Visible = 'off';
            app.ESTOPButton.Enable = 'on';
            app.ConfirmResumeButton.Enable = 'off';
        end

        % Value changed function: RollButton
        function RollButtonValueChanged(app, event)
            app.rpos = app.RollButton.Value;
        end

        % Value changed function: RollButton_2
        function RollButton_2ValueChanged(app, event)
            app.rneg = app.RollButton_2.Value;
        end

        % Value changed function: PitchButton
        function PitchButtonValueChanged(app, event)
            app.ppos = app.PitchButton.Value;
        end

        % Value changed function: PitchButton_2
        function PitchButton_2ValueChanged(app, event)
            app.pneg = app.PitchButton_2.Value;
        end

        % Value changed function: YawButton
        function YawButtonValueChanged(app, event)
            app.ywpos = app.YawButton.Value;
        end

        % Value changed function: YawButton_2
        function YawButton_2ValueChanged(app, event)
            app.ywneg = app.YawButton_2.Value;
        end

        % Button pushed function: SpawnObstacleButton
        function SpawnObstacleButtonPushed(app, event)
            %Run function that takes environment
            %Run code to spawn an object on the path of the robot
        end

        % Value changed function: ChooseRobot
        function ChooseRobotValueChanged(app, event)
            value = app.ChooseRobot.Value;
            app.currentrobot = value;
            if value == 0
                app.ChooseRobot.Text = "Linear UR3";
            elseif value == 1
                app.ChooseRobot.Text = "Dobot CR3";
            end
            app.updateQvalues;
        end

        % Value changed function: q1Slider
        function q1SliderValueChanged(app, event)
            if app.currentrobot == 0
                app.qrobot1(2) = deg2rad(app.q1Slider.Value);
            elseif app.currentrobot == 1
                app.qrobot2(1) = deg2rad(app.q1Slider.Value);
            end
        end

        % Value changing function: q1Slider
        function q1SliderValueChanging(app, event)
            if app.currentrobot == 0
                app.qrobot1(2) = deg2rad(event.Value);
            elseif app.currentrobot == 1
                app.qrobot2(1) = deg2rad(event.Value);
            end
        end

        % Value changed function: q2Slider
        function q2SliderValueChanged(app, event)
            if app.currentrobot == 0
                app.qrobot1(3) = deg2rad(app.q2Slider.Value);
            elseif app.currentrobot == 1
                app.qrobot2(2) = deg2rad(app.q2Slider.Value);
            end 
        end

        % Value changing function: q2Slider
        function q2SliderValueChanging(app, event)
            if app.currentrobot == 0
                app.qrobot1(3) = deg2rad(event.Value);
            elseif app.currentrobot == 1
                app.qrobot2(2) = deg2rad(event.Value);
            end
        end

        % Value changed function: q3Slider
        function q3SliderValueChanged(app, event)
            if app.currentrobot == 0
                app.qrobot1(4) = deg2rad(app.q3Slider.Value);
            elseif app.currentrobot == 1
                app.qrobot2(3) = deg2rad(app.q3Slider.Value);
            end
        end

        % Value changing function: q3Slider
        function q3SliderValueChanging(app, event)
            if app.currentrobot == 0
                app.qrobot1(4) = deg2rad(event.Value);
            elseif app.currentrobot == 1
                app.qrobot2(3) = deg2rad(event.Value);
            end
        end

        % Value changed function: q4Slider
        function q4SliderValueChanged(app, event)
            if app.currentrobot == 0
                app.qrobot1(5) = deg2rad(app.q4Slider.Value);
            elseif app.currentrobot == 1
                app.qrobot2(4) = deg2rad(app.q4Slider.Value);
            end
        end

        % Value changing function: q4Slider
        function q4SliderValueChanging(app, event)
            if app.currentrobot == 0
                app.qrobot1(5) = deg2rad(event.Value);
            elseif app.currentrobot == 1
                app.qrobot2(4) = deg2rad(event.Value);
            end
        end

        % Value changed function: q5Slider
        function q5SliderValueChanged(app, event)
            if app.currentrobot == 0
                app.qrobot1(6) = deg2rad(app.q5Slider.Value);
            elseif app.currentrobot == 1
                app.qrobot2(5) = deg2rad(app.q5Slider.Value);
            end
        end

        % Value changing function: q5Slider
        function q5SliderValueChanging(app, event)
            if app.currentrobot == 0
                app.qrobot1(6) = deg2rad(event.Value);
            elseif app.currentrobot == 1
                app.qrobot2(5) = deg2rad(event.Value);
            end
        end

        % Value changed function: q6Slider
        function q6SliderValueChanged(app, event)
            if app.currentrobot == 0
                app.qrobot1(7) = deg2rad(app.q6Slider.Value);
            elseif app.currentrobot == 1
                app.qrobot2(6) = deg2rad(app.q6Slider.Value);
            end
        end

        % Value changing function: q6Slider
        function q6SliderValueChanging(app, event)
            if app.currentrobot == 0
                app.qrobot1(7) = deg2rad(event.Value);
            elseif app.currentrobot == 1
                app.qrobot2(6) = deg2rad(event.Value);
            end 
        end

        % Value changed function: RoverPositionSlider
        function RoverPositionSliderValueChanged(app, event)
            if app.currentrobot == 0
                app.qrobot1(1) = app.RoverPositionSlider.Value;
            end 
        end

        % Value changing function: RoverPositionSlider
        function RoverPositionSliderValueChanging(app, event)
            if app.currentrobot == 0
                app.qrobot1(1) = event.Value;
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RoverUR3JoggingLabel = uilabel(app.UIFigure);
            app.RoverUR3JoggingLabel.Text = 'Rover UR3 Jogging';
            app.XButton = uibutton(app.UIFigure, 'state');
            app.XButton.ValueChangedFcn = createCallbackFcn(app, @XButtonValueChanged, true);
            app.XButton.Text = 'X+';
            app.XButton_2 = uibutton(app.UIFigure, 'state');
            app.XButton_2.ValueChangedFcn = createCallbackFcn(app, @XButton_2ValueChanged, true);
            app.XButton_2.Text = 'X-';
            app.YButton = uibutton(app.UIFigure, 'state');
            app.YButton.ValueChangedFcn = createCallbackFcn(app, @YButtonValueChanged, true);
            app.YButton.Text = 'Y+';
            app.YButton_2 = uibutton(app.UIFigure, 'state');
            app.YButton_2.ValueChangedFcn = createCallbackFcn(app, @YButton_2ValueChanged, true);
            app.YButton_2.Text = 'Y-';
            app.ZButton = uibutton(app.UIFigure, 'state');
            app.ZButton.ValueChangedFcn = createCallbackFcn(app, @ZButtonValueChanged, true);
            app.ZButton.Text = 'Z+';
            app.ZButton_2 = uibutton(app.UIFigure, 'state');
            app.ZButton_2.ValueChangedFcn = createCallbackFcn(app, @ZButton_2ValueChanged, true);
            app.ZButton_2.Text = 'Z-';
            app.RollButton = uibutton(app.UIFigure, 'state');
            app.RollButton.ValueChangedFcn = createCallbackFcn(app, @RollButtonValueChanged, true);
            app.RollButton.Text = 'Roll+';
            app.RollButton_2 = uibutton(app.UIFigure, 'state');
            app.RollButton_2.ValueChangedFcn = createCallbackFcn(app, @RollButton_2ValueChanged, true);
            app.RollButton_2.Text = 'Roll-';
            app.PitchButton = uibutton(app.UIFigure, 'state');
            app.PitchButton.ValueChangedFcn = createCallbackFcn(app, @PitchButtonValueChanged, true);
            app.PitchButton.Text = 'Pitch+';
            app.PitchButton_2 = uibutton(app.UIFigure, 'state');
            app.PitchButton_2.ValueChangedFcn = createCallbackFcn(app, @PitchButton_2ValueChanged, true);
            app.PitchButton_2.Text = 'Pitch-';
            app.YawButton = uibutton(app.UIFigure, 'state');
            app.YawButton.ValueChangedFcn = createCallbackFcn(app, @YawButtonValueChanged, true);
            app.YawButton.Text = 'Yaw+';
            app.YawButton_2 = uibutton(app.UIFigure, 'state');
            app.YawButton_2.ValueChangedFcn = createCallbackFcn(app, @YawButton_2ValueChanged, true);
            app.YawButton_2.Text = 'Yaw-';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E  -  S  T  O  P';
            app.ConfirmResumeButton = uibutton(app.UIFigure, 'push');
            app.ConfirmResumeButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmResumeButtonPushed, true);
            app.ConfirmResumeButton.Enable = 'off';
            app.ConfirmResumeButton.Visible = 'off';
            app.ConfirmResumeButton.Text = 'Confirm Resume';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Enable = 'off';
            app.Button.Text = '';
            app.q1SliderLabel = uilabel(app.UIFigure);
            app.q1SliderLabel.Text = 'q1';
            app.q1Slider = uislider(app.UIFigure);
            app.q1Slider.ValueChangedFcn = createCallbackFcn(app, @q1SliderValueChanged, true);
            app.q1Slider.ValueChangingFcn = createCallbackFcn(app, @q1SliderValueChanging, true);
            app.q2SliderLabel = uilabel(app.UIFigure);
            app.q2SliderLabel.Text = 'q2';
            app.q2Slider = uislider(app.UIFigure);
            app.q2Slider.ValueChangedFcn = createCallbackFcn(app, @q2SliderValueChanged, true);
            app.q2Slider.ValueChangingFcn = createCallbackFcn(app, @q2SliderValueChanging, true);
            app.q3SliderLabel = uilabel(app.UIFigure);
            app.q3SliderLabel.Text = 'q3';
            app.q3Slider = uislider(app.UIFigure);
            app.q3Slider.ValueChangedFcn = createCallbackFcn(app, @q3SliderValueChanged, true);
            app.q3Slider.ValueChangingFcn = createCallbackFcn(app, @q3SliderValueChanging, true);
            app.q4SliderLabel = uilabel(app.UIFigure);
            app.q4SliderLabel.Text = 'q4';
            app.q4Slider = uislider(app.UIFigure);
            app.q4Slider.ValueChangedFcn = createCallbackFcn(app, @q4SliderValueChanged, true);
            app.q4Slider.ValueChangingFcn = createCallbackFcn(app, @q4SliderValueChanging, true);
            app.q5SliderLabel = uilabel(app.UIFigure);
            app.q5SliderLabel.Text = 'q5';
            app.q5Slider = uislider(app.UIFigure);
            app.q5Slider.ValueChangedFcn = createCallbackFcn(app, @q5SliderValueChanged, true);
            app.q5Slider.ValueChangingFcn = createCallbackFcn(app, @q5SliderValueChanging, true);
            app.q6SliderLabel = uilabel(app.UIFigure);
            app.q6SliderLabel.Text = 'q6';
            app.q6Slider = uislider(app.UIFigure);
            app.q6Slider.ValueChangedFcn = createCallbackFcn(app, @q6SliderValueChanged, true);
            app.q6Slider.ValueChangingFcn = createCallbackFcn(app, @q6SliderValueChanging, true);
            app.RoverPositionSliderLabel = uilabel(app.UIFigure);
            app.RoverPositionSliderLabel.Text = 'Rover Position';
            app.RoverPositionSlider = uislider(app.UIFigure);
            app.RoverPositionSlider.ValueChangedFcn = createCallbackFcn(app, @RoverPositionSliderValueChanged, true);
            app.RoverPositionSlider.ValueChangingFcn = createCallbackFcn(app, @RoverPositionSliderValueChanging, true);
            app.SpawnObstacleButton = uibutton(app.UIFigure, 'push');
            app.SpawnObstacleButton.ButtonPushedFcn = createCallbackFcn(app, @SpawnObstacleButtonPushed, true);
            app.SpawnObstacleButton.Text = 'Spawn Obstacle';
            app.ChooseRobot = uibutton(app.UIFigure, 'state');
            app.ChooseRobot.ValueChangedFcn = createCallbackFcn(app, @ChooseRobotValueChanged, true);
            app.ChooseRobot.Text = 'Rover UR3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end